classdef Jrx_team7 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        HomePanel                    matlab.ui.container.Panel
        WorkoutHistoryButton         matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        WanttoviewpastworkoutsLabel  matlab.ui.control.Label
        ViewProgressButton           matlab.ui.control.Button
        profileButton                matlab.ui.control.Button
        LogNewWorkoutButton          matlab.ui.control.Button
        ReadytorecordyourworkoutstatsLabel  matlab.ui.control.Label
        workoutPanel                 matlab.ui.container.Panel
        ViewReportButton             matlab.ui.control.Button
        BackButton                   matlab.ui.control.Button
        ActivityDropDown             matlab.ui.control.DropDown
        ActivityDropDownLabel        matlab.ui.control.Label
        AverageHeartRateEditField    matlab.ui.control.EditField
        EditFieldLabel               matlab.ui.control.Label
        DurationofsessionEditField   matlab.ui.control.EditField
        StartTimeEditFieldLabel      matlab.ui.control.Label
        RecordyourworkoutstatsLabel  matlab.ui.control.Label
        workouthistoryPanel          matlab.ui.container.Panel
        FeedbackTextArea             matlab.ui.control.TextArea
        FeedbackTextAreaLabel        matlab.ui.control.Label
        progressPanel                matlab.ui.container.Panel
        hrPercentLabel               matlab.ui.control.Label
        HomeButton                   matlab.ui.control.Button
        ProgressTowardsGoalLabel     matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        ContinueButton               matlab.ui.control.Button
        EnterAgeEditField            matlab.ui.control.EditField
        EnterAgeEditFieldLabel       matlab.ui.control.Label
        SkillLevelDropDown           matlab.ui.control.DropDown
        SkillLevelDropDownLabel      matlab.ui.control.Label
        FitnessGoalDropDown          matlab.ui.control.DropDown
        FitnessGoalDropDownLabel     matlab.ui.control.Label
        WelcometoJRXFitnessLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        UserGoal % Stores goal value such as 'Endurance','Weightloss', etc
        UserAge %store user's exact age value
        UserSkillLevel %Stores 'Beginner', 'Intermediate', etc
        UserMaxHR % calculate using formula (220-age)
        WorkoutHistoryTable = table() %database for graph

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ContinueButton
        function ContinueButtonPushed2(app, event)
            disp("Continue clicked")

            app.HomePanel.Visible = "on";
        end

        % Button pushed function: LogNewWorkoutButton
        function LogNewWorkoutButtonPushed(app, event)
            app.HomePanel.Visible = "off";
            app.workoutPanel.Visible = "on";
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app.workoutPanel.Visible = "off";
            app.HomePanel.Visible = "on";
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            app.progressPanel.Visible = "off";
            app.HomePanel.Visible = "on";
        end

        % Button pushed function: WorkoutHistoryButton
        function workouthistoryButtonPushed3(app, event)
            app.workouthistoryPanel.Visible = "on";
            app.HomePanel.Visible = "off";
        end

        % Button pushed function: ViewProgressButton
        function ViewProgressButtonPushed(app, event)
            app.HomePanel.Visible = "off";
            app.progressPanel.Visible = "on";
        end

        % Button pushed function: ViewReportButton
        function ViewReportButtonPushed(app, event)
           % Get workout activity
userActivity = app.ActivityDropDown.Value;

% Feedback based on activity
if strcmp(userActivity,"Yoga")
    feedback = "Great for endurance!";
elseif strcmp(userActivity,"Weightlifting")
    feedback = "Focus on strength!";
else
    feedback = "Keep up the great work!";
end

% Convert text fields to numbers
userAge = str2double(app.EnterAgeEditField.Value);
userAvgHR = str2double(app.AverageHeartRateEditField.Value);
userSessionDuration = str2double(app.DurationofsessionEditField.Value);

% Check for invalid inputs
if isnan(userAge) || isnan(userAvgHR) || isnan(userSessionDuration)
    uialert(app.UIFigure, ...
        "Please enter valid numbers for Age, Session Duration, and Average Heart Rate.", ...
        "Invalid Input");
    return;
end

% Calculate maximum heart rate
userMaxHR = 220 - userAge;

% Calculate heart rate percentage
hrPercent = (userAvgHR / userMaxHR) * 100;
%==========================================
% Update Workout Progress Graph
%==========================================

% Graph data
time = [0 userSessionDuration];
heartRate = [0 userAvgHR];

% Plot graph
plot(app.UIAxes,time,heartRate,'-o');

% Graph labels
xlabel(app.UIAxes,"Workout Time (minutes)");
ylabel(app.UIAxes,"Heart Rate (BPM)");
title(app.UIAxes,"Heart Rate Progress");
grid(app.UIAxes,"on");

% Display heart rate percentage
app.hrPercentLabel.Text = sprintf("Heart Rate Zone: %.1f%%", hrPercent);

app.hrPercentLabel.Text = sprintf("Heart Rate Zone: %.1f%%", hrPercent);

% Display result
app.hrPercentLabel.Text = sprintf("Heart Rate Zone: %.1f%%", hrPercent);
% Calculate heart rate percentage           
            % =========================
            % DURATION FEEDBACK
            % =========================
        
            %HR percentage used to configure heart rate zone
            
            sessionDuration = app.DurationofsessionEditField.Value;
            userAvgHR = app.AverageHeartRateEditField.Value;
            
            activity =app.ActivityDropDown.Value;

            % HR percentage used to configure heart rate zone

% Temporary heart rate value
userAvgHR = 150;

% Get age from text field and convert to number
userAge = str2double(app.EnterAgeEditField.Value);

% Get average heart rate from text field and convert to number
userAvgHR = str2double(app.AverageHeartRateEditField.Value);

% Check values
if isnan(userAge) || isnan(userAvgHR)
    uialert(app.UIFigure,"Enter valid numbers for age and heart rate.","Input Error");
    return;
end

% Calculate max heart rate
userMaxHR = 220 - userAge;

% Force both values to be single numbers
userAvgHR = userAvgHR(1);
userMaxHR = userMaxHR(1);

% Calculate HR percentage
disp("USER AVG HR:")
disp(userAvgHR)

disp("USER MAX HR:")
disp(userMaxHR)

size(userAvgHR)
size(userMaxHR)

hrPercent = (userAvgHR / userMaxHR) * 100;
% Display HR percentage
app.hrPercentLabel.Text = sprintf("Heart Rate Zone: %.1f%%", hrPercent);
            sentence1 = '';
            sentence2 = '';
            sentence3 = '';
            %DURATION
if sessionDuration < 30
    app.ReadytorecordyourworkoutstatsLabel.Text = "Try increasing workout duration for better results!";
end
                if app.UserAge < 65
                    sentence1 = "For endurance, you need longer workouts. Try to scale up past 30 minutes.";
                else
                    % This handles your "else -> if beginner or intermediate ok for now try to up, if advanced aim 45"
                    if strcmp(app.UserSkillLevel, 'Advanced')
                        sentence2 = "As an advanced athlete over 65, a 30-minute workout is a start, but you should aim for 45 minutes.";
                    else
                        sentence3 = "For your skill level at age 65+, less than 30 minutes is okay for now, but try to increase it.";

                    end
                end
               %==========================================
% Display Feedback Report
%==========================================

% Convert sentences to character vectors
sentence1 = char(string(sentence1));
sentence2 = char(string(sentence2));
sentence3 = char(string(sentence3));

% Display report
app.FeedbackTextArea.Value = {
    'JRX FITNESS WORKOUT REPORT'
    ''
    sentence1
    ''
    sentence2
    ''
    sentence3
};

% Calculate maximum heart rate and HR percentage
app.UserMaxHR = 220 - app.UserAge;
hrPercent = (userAvgHR / userMaxHR) * 100;
%==========================================
% Initialize Feedback Sentences
%==========================================
sentence2 = "";
sentence3 = "";
%% ==========================
% DURATION FEEDBACK
%===========================

if sessionDuration < 30

    if app.UserAge < 65
        sentence1 = "For endurance, you need longer workouts. Try to scale up past 30 minutes.";
    else
        if strcmp(app.UserSkillLevel,"Advanced")
            sentence1 = "As an advanced athlete over 65, a 30-minute workout is a start, but you should aim for 45 minutes.";
        else
            sentence1 = "For your skill level at age 65+, less than 30 minutes is okay for now, but try to increase it.";
        end
    end

elseif sessionDuration < 45

    sentence1 = "Your duration is better, but going above 45 minutes is good for endurance training.";

elseif sessionDuration <= 90

    sentence1 = "Excellent session duration! This is a good range for endurance, and you can safely go up to 90 minutes.";

else

    if app.UserAge < 65
        sentence1 = "Great job logging over 90 minutes! It is a good endurance push, but be careful not to overexert.";
    else
        if strcmp(app.UserSkillLevel,"Advanced")
            sentence1 = "Impressive stamina! As an advanced senior athlete, over 90 minutes is acceptable, but listen to your body.";
        else
            sentence1 = "Warning: Exercising above 90 minutes at age 65+ means you might be overexerting for your level.";
        end
    end

%==========================================
% Display Feedback Report
%==========================================
end
reportText = [
    "JRX FITNESS WORKOUT REPORT"
    ""
    string(sentence1)
    ""
    string(sentence2)
    ""
    string(sentence3)
];

app.FeedbackTextArea.Value = cellstr(reportText);

%% ==========================
% FITNESS GOAL FEEDBACK
%===========================

if strcmp(app.UserGoal,"Endurance")

    if matches(activity,["Treadmill","Cycling","Elliptical"])
        sentence2 = sprintf("Choosing %s is directly in line with your fitness goal!",activity);
    else
        sentence2 = sprintf("While %s offers functional movement, switching to treadmill, cycling, or the elliptical will improve your endurance gains.",activity);
    end

elseif strcmp(app.UserGoal,"Weightloss")

    if matches(activity,["Treadmill","Cycling","Elliptical"])
        sentence2 = sprintf("Choosing %s is highly effective for burning calories and supporting weight loss.",activity);
    else
        sentence2 = sprintf("While %s burns calories, treadmill, cycling, or the elliptical are generally more effective for weight loss.",activity);
    end

elseif strcmp(app.UserGoal,"Strength")

    if strcmp(activity,"Weight Lifting")
        sentence2 = "Weight Lifting is the best exercise for building muscular strength.";
    else
        sentence2 = sprintf("%s improves cardiovascular fitness, but Weight Lifting should be included for strength development.",activity);
    end

else

    sentence2 = sprintf("Choosing %s supports overall health and physical wellness.",activity);

end
%==========================================
% Display Feedback Report
%==========================================

reportText = [
    "JRX FITNESS WORKOUT REPORT"
    ""
    string(sentence1)
    ""
    string(sentence2)
    ""
    string(sentence3)
];

app.FeedbackTextArea.Value = cellstr(reportText);

%% ==========================
% HEART RATE FEEDBACK
%===========================

if strcmp(app.UserGoal,"Overall Health")

    if hrPercent >= 70 && sessionDuration >= 30
        sentence3 = sprintf("Great job! Your average heart rate of %d BPM met the recommended target for overall health.",round(userAvgHR));
    else
        sentence3 = sprintf("Aim to maintain about %d BPM for at least 30 minutes to maximize health benefits.",round(userAvgHR));
    end

elseif strcmp(app.UserGoal,"Weightloss")

    if hrPercent >= 50 && hrPercent < 70
        sentence3 = sprintf("Excellent! An average heart rate of %d BPM keeps you in the ideal fat-burning zone.",round(userAvgHR));
    else
        sentence3 = sprintf("Your average heart rate was %d BPM. Try to stay between 50%% and 70%% of your maximum heart rate for weight loss.",round(userAvgHR));
    end

elseif strcmp(app.UserGoal,"Endurance")

    if hrPercent >= 60 && hrPercent < 80
        sentence3 = sprintf("Excellent! Your average heart rate of %d BPM is ideal for endurance training.",round(userAvgHR));
    else
        sentence3 = sprintf("Your average heart rate was %d BPM. Aim for 60%%-80%% of your maximum heart rate for endurance training.",round(userAvgHR));
    end

elseif strcmp(app.UserGoal,"Strength")

    if hrPercent >= 70
        sentence3 = sprintf("Great intensity! Your average heart rate of %d BPM is appropriate for challenging strength workouts.",round(userAvgHR));
    else
        sentence3 = sprintf("Your average heart rate was %d BPM. Increase your workout intensity slightly during lifting sessions.",round(userAvgHR));
    end

end
%==========================================
% Display Feedback Report
%==========================================

reportText = [
    "JRX FITNESS WORKOUT REPORT"
    ""
    string(sentence1)
    ""
    string(sentence2)
    ""
    string(sentence3)
];

app.FeedbackTextArea.Value = cellstr(reportText);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WelcometoJRXFitnessLabel = uilabel(app.UIFigure);
            app.WelcometoJRXFitnessLabel.Text = 'Welcome to JRX Fitness';
            app.FitnessGoalDropDownLabel = uilabel(app.UIFigure);
            app.FitnessGoalDropDownLabel.Text = 'Fitness Goal:';
            app.FitnessGoalDropDown = uidropdown(app.UIFigure);
            app.FitnessGoalDropDown.Items = {'Select goal', 'Endurance', 'Overall Health', 'Weightloss', 'Strength'};
            app.FitnessGoalDropDown.Value = 'Select goal';
            app.SkillLevelDropDownLabel = uilabel(app.UIFigure);
            app.SkillLevelDropDownLabel.Text = 'Skill Level:';
            app.SkillLevelDropDown = uidropdown(app.UIFigure);
            app.SkillLevelDropDown.Items = {'Select level', 'Beginner', 'Intermediate', 'Advanced'};
            app.SkillLevelDropDown.Value = 'Select level';
            app.EnterAgeEditFieldLabel = uilabel(app.UIFigure);
            app.EnterAgeEditFieldLabel.Text = 'Enter Age';
            app.EnterAgeEditField = uieditfield(app.UIFigure, 'text');
            app.EnterAgeEditField.Value = '0';
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed2, true);
            app.ContinueButton.Text = 'Continue';
            app.progressPanel = uipanel(app.UIFigure);
            app.progressPanel.Title = 'progress';
            app.progressPanel.Visible = 'off';
            app.UIAxes = uiaxes(app.progressPanel);
            title(app.UIAxes, 'Heart Rate')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ProgressTowardsGoalLabel = uilabel(app.progressPanel);
            app.ProgressTowardsGoalLabel.Text = 'Progress Towards Goal:';
            app.HomeButton = uibutton(app.progressPanel, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.hrPercentLabel = uilabel(app.progressPanel);
            app.hrPercentLabel.Text = 'HRPercent: ';
            app.workouthistoryPanel = uipanel(app.UIFigure);
            app.workouthistoryPanel.Title = 'workout history';
            app.workouthistoryPanel.Visible = 'off';
            app.FeedbackTextAreaLabel = uilabel(app.workouthistoryPanel);
            app.FeedbackTextAreaLabel.Text = 'Feedback';
            app.FeedbackTextArea = uitextarea(app.workouthistoryPanel);
            app.FeedbackTextArea.Tag = 'FeedbackTextArea';
            app.workoutPanel = uipanel(app.UIFigure);
            app.workoutPanel.Title = 'workout';
            app.workoutPanel.Visible = 'off';
            app.RecordyourworkoutstatsLabel = uilabel(app.workoutPanel);
            app.RecordyourworkoutstatsLabel.Text = 'Record your workout stats:';
            app.StartTimeEditFieldLabel = uilabel(app.workoutPanel);
            app.StartTimeEditFieldLabel.Text = 'Duration of session';
            app.DurationofsessionEditField = uieditfield(app.workoutPanel, 'text');
            app.DurationofsessionEditField.Value = 'enter';
            app.EditFieldLabel = uilabel(app.workoutPanel);
            app.EditFieldLabel.Text = 'Average Heart Rate';
            app.AverageHeartRateEditField = uieditfield(app.workoutPanel, 'text');
            app.AverageHeartRateEditField.Value = 'bpm';
            app.ActivityDropDownLabel = uilabel(app.workoutPanel);
            app.ActivityDropDownLabel.Text = 'Activity';
            app.ActivityDropDown = uidropdown(app.workoutPanel);
            app.ActivityDropDown.Items = {'select', 'yoga', 'elliptical', 'weightlifting', 'walking', 'cycling', 'bodyweight training', 'treadmill running'};
            app.ActivityDropDown.Value = 'select';
            app.BackButton = uibutton(app.workoutPanel, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.ViewReportButton = uibutton(app.workoutPanel, 'push');
            app.ViewReportButton.ButtonPushedFcn = createCallbackFcn(app, @ViewReportButtonPushed, true);
            app.ViewReportButton.Text = 'View Report';
            app.HomePanel = uipanel(app.UIFigure);
            app.HomePanel.Title = 'Home';
            app.HomePanel.Visible = 'off';
            app.ReadytorecordyourworkoutstatsLabel = uilabel(app.HomePanel);
            app.ReadytorecordyourworkoutstatsLabel.Text = ' Ready to record your workout stats?';
            app.LogNewWorkoutButton = uibutton(app.HomePanel, 'push');
            app.LogNewWorkoutButton.ButtonPushedFcn = createCallbackFcn(app, @LogNewWorkoutButtonPushed, true);
            app.LogNewWorkoutButton.Text = 'Log New Workout';
            app.profileButton = uibutton(app.HomePanel, 'push');
            app.profileButton.Text = 'profile';
            app.ViewProgressButton = uibutton(app.HomePanel, 'push');
            app.ViewProgressButton.ButtonPushedFcn = createCallbackFcn(app, @ViewProgressButtonPushed, true);
            app.ViewProgressButton.Text = 'View Progress';
            app.WanttoviewpastworkoutsLabel = uilabel(app.HomePanel);
            app.WanttoviewpastworkoutsLabel.Text = 'Want to know if your close to meeting your fitness goal?';
            app.Label = uilabel(app.HomePanel);
            app.Label.Text = 'Want to view past sessions & reccomendations?';
            app.WorkoutHistoryButton = uibutton(app.HomePanel, 'push');
            app.WorkoutHistoryButton.ButtonPushedFcn = createCallbackFcn(app, @workouthistoryButtonPushed3, true);
            app.WorkoutHistoryButton.Text = 'Workout History';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Jrx_team7

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end